classdef View < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        tbNewToolboxUIFigure           matlab.ui.Figure
        EditButton                     matlab.ui.control.Button
        GetplannedactionsButton        matlab.ui.control.Button
        PlannedActionsTextArea         matlab.ui.control.TextArea
        CreateButton                   matlab.ui.control.Button
        CreatenewtoolboxLabel          matlab.ui.control.Label
        GithubPanel                    matlab.ui.container.Panel
        RepoNameEditField              matlab.ui.control.EditField
        RepoNameEditFieldLabel         matlab.ui.control.Label
        ShortDecriptionEditField       matlab.ui.control.EditField
        ShortDecriptionEditFieldLabel  matlab.ui.control.Label
        VisibilityDropDown             matlab.ui.control.DropDown
        VisibilityDropDownLabel        matlab.ui.control.Label
        URLDropDown                    matlab.ui.control.DropDown
        URLDropDownLabel               matlab.ui.control.Label
        ToolboxConfigurationFilePanel  matlab.ui.container.Panel
        DependenciesLabel              matlab.ui.control.Label
        DependenciesListBox            matlab.ui.control.ListBox
        Label                          matlab.ui.control.Label
        AvailableToolboxesLabel        matlab.ui.control.Label
        ToolboxesListBox               matlab.ui.control.ListBox
        FilterToolboxesEditField       matlab.ui.control.EditField
        PathplacementDropDown          matlab.ui.control.DropDown
        PathplacementDropDownLabel     matlab.ui.control.Label
        SubfolderEditField             matlab.ui.control.EditField
        SubfolderEditFieldLabel        matlab.ui.control.Label
        ToolboxnameEditField           matlab.ui.control.EditField
        ToolboxnameEditFieldLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        model      % MVC model
        controller % MVC controller
    end
    
    methods (Access = public)
        function init(app, controller, model)
            app.controller = controller;
            app.model = model;
            
            % set default values
            app.ToolboxesListBox.Items = model.toolboxNames;
            app.ToolboxnameEditField.Value = model.getNewToolboxName;
            app.URLDropDown.Items = model.getGithubUrls;
            app.VisibilityDropDown.Value = model.getDefaultGithubVisibility;
            %app.PlannedActionsTextArea.Value = model.getPlannedActions;
            app.RepoNameEditField.Value = model.getDefaultRepoName;
        end              

        function s = getSubfolder(app)
            s = app.SubfolderEditField.Value;
        end
        
        function s = getShortDescription(app)
            s = app.ShortDecriptionEditField.Value;
        end
        
        function deps = getDependencies(app)
            deps = app.DependenciesListBox.Value;
        end
        
        function p = getPathPlacement(app)
            p = app.PathplacementDropDown.Value;
        end
        
        function v = getVisibility(app)
            v = app.VisibilityDropDown.Value;
        end
        
        function s = getGithubUrl(app)
            s = app.URLDropDown.Value;
        end

        function s = getGithubRepoName(app)
            s = app.RepoNameEditField.Value;
        end

        function setCreationState(app)
            app.CreateButton.Enable = true;
            app.ToolboxConfigurationFilePanel.Enable = 'off';
            app.GithubPanel.Enable = 'off';
            app.EditButton.Enable = true;
            app.GetplannedactionsButton.Enable = false;
        end

        function setEditState(app)
            app.CreateButton.Enable = false;
            app.ToolboxConfigurationFilePanel.Enable = 'on';
            app.GithubPanel.Enable = 'on';
            app.EditButton.Enable = false;
            app.GetplannedactionsButton.Enable = true;
        end

        function setDoneState(app)
            % could as well just close the app, but maybe there's still
            % interesting info in the exectued actions
            app.CreateButton.Enable = false;
            app.EditButton.Enable = false;
        end

        function setActionText(app, s)
            app.PlannedActionsTextArea.Value = s;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            app.controller.createToolbox;
        end

        % Value changing function: FilterToolboxesEditField
        function FilterToolboxesEditFieldValueChanging(app, event)
            filteredToolboxNames = app.controller.filterToolboxes(event.Value);
            app.ToolboxesListBox.Items = filteredToolboxNames;
        end

        % Button pushed function: GetplannedactionsButton
        function GetplannedactionsButtonPushed(app, event)
            app.controller.plannedActions;
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            app.setEditState;
        end

        % Callback function
        function onChoosingToolbox(app, event)

        end

        % Double-clicked callback: ToolboxesListBox
        function onAddingToolbox(app, event)
            value = string(app.ToolboxesListBox.Value);
            app.DependenciesListBox.Items{end+1} = char(value);
            app.ToolboxesListBox.Items(string(app.ToolboxesListBox.Items) == value) = [];            
        end

        % Double-clicked callback: DependenciesListBox
        function onRemovingToolbox(app, event)
            value = string(app.DependenciesListBox.Value);
            app.ToolboxesListBox.Items{end+1} = char(value);
            app.DependenciesListBox.Items(string(app.DependenciesListBox.Items) == value) = [];           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.tbNewToolboxUIFigure = uifigure('Visible', 'off');
            app.tbNewToolboxUIFigure.Name = 'tbNewToolbox';
            app.ToolboxConfigurationFilePanel = uipanel(app.tbNewToolboxUIFigure);
            app.ToolboxConfigurationFilePanel.Title = 'Toolbox Configuration File';
            app.ToolboxnameEditFieldLabel = uilabel(app.ToolboxConfigurationFilePanel);
            app.ToolboxnameEditFieldLabel.Text = 'Toolbox name';
            app.ToolboxnameEditField = uieditfield(app.ToolboxConfigurationFilePanel, 'text');
            app.ToolboxnameEditField.Editable = 'off';
            app.ToolboxnameEditField.Enable = 'off';
            app.SubfolderEditFieldLabel = uilabel(app.ToolboxConfigurationFilePanel);
            app.SubfolderEditFieldLabel.Text = 'Subfolder';
            app.SubfolderEditField = uieditfield(app.ToolboxConfigurationFilePanel, 'text');
            app.PathplacementDropDownLabel = uilabel(app.ToolboxConfigurationFilePanel);
            app.PathplacementDropDownLabel.Text = 'Path placement';
            app.PathplacementDropDown = uidropdown(app.ToolboxConfigurationFilePanel);
            app.PathplacementDropDown.Items = {'none', 'prependrootonly', 'prepend', 'appendrootonly', 'append', 'mltbx'};
            app.PathplacementDropDown.Value = 'append';
            app.FilterToolboxesEditField = uieditfield(app.ToolboxConfigurationFilePanel, 'text');
            app.FilterToolboxesEditField.ValueChangingFcn = createCallbackFcn(app, @FilterToolboxesEditFieldValueChanging, true);
            app.ToolboxesListBox = uilistbox(app.ToolboxConfigurationFilePanel);
            app.ToolboxesListBox.DoubleClickedFcn = createCallbackFcn(app, @onAddingToolbox, true);
            app.ToolboxesListBox.Value = {};
            app.AvailableToolboxesLabel = uilabel(app.ToolboxConfigurationFilePanel);
            app.AvailableToolboxesLabel.Text = 'Available toolboxes (double-click to add dependency)';
            app.Label = uilabel(app.ToolboxConfigurationFilePanel);
            app.Label.Text = '';
            app.DependenciesListBox = uilistbox(app.ToolboxConfigurationFilePanel);
            app.DependenciesListBox.Items = {};
            app.DependenciesListBox.DoubleClickedFcn = createCallbackFcn(app, @onRemovingToolbox, true);
            app.DependenciesListBox.Value = {};
            app.DependenciesLabel = uilabel(app.ToolboxConfigurationFilePanel);
            app.DependenciesLabel.Text = 'Dependencies';
            app.GithubPanel = uipanel(app.tbNewToolboxUIFigure);
            app.GithubPanel.Title = 'Github';
            app.URLDropDownLabel = uilabel(app.GithubPanel);
            app.URLDropDownLabel.Text = 'URL';
            app.URLDropDown = uidropdown(app.GithubPanel);
            app.VisibilityDropDownLabel = uilabel(app.GithubPanel);
            app.VisibilityDropDownLabel.Text = 'Visibility';
            app.VisibilityDropDown = uidropdown(app.GithubPanel);
            app.VisibilityDropDown.Items = {'public', 'private', 'internal'};
            app.VisibilityDropDown.Value = 'public';
            app.ShortDecriptionEditFieldLabel = uilabel(app.GithubPanel);
            app.ShortDecriptionEditFieldLabel.Text = 'Short Decription';
            app.ShortDecriptionEditField = uieditfield(app.GithubPanel, 'text');
            app.RepoNameEditFieldLabel = uilabel(app.GithubPanel);
            app.RepoNameEditFieldLabel.Text = 'Repo Name';
            app.RepoNameEditField = uieditfield(app.GithubPanel, 'text');
            app.CreatenewtoolboxLabel = uilabel(app.tbNewToolboxUIFigure);
            app.CreatenewtoolboxLabel.Text = 'Create new toolbox';
            app.CreateButton = uibutton(app.tbNewToolboxUIFigure, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Enable = 'off';
            app.CreateButton.Text = 'Create';
            app.PlannedActionsTextArea = uitextarea(app.tbNewToolboxUIFigure);
            app.PlannedActionsTextArea.Editable = 'off';
            app.GetplannedactionsButton = uibutton(app.tbNewToolboxUIFigure, 'push');
            app.GetplannedactionsButton.ButtonPushedFcn = createCallbackFcn(app, @GetplannedactionsButtonPushed, true);
            app.GetplannedactionsButton.Text = 'Get planned actions';
            app.EditButton = uibutton(app.tbNewToolboxUIFigure, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Enable = 'off';
            app.EditButton.Text = 'Edit';
            % Show the figure after all components are created
            app.tbNewToolboxUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = View

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.tbNewToolboxUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.tbNewToolboxUIFigure)
        end
    end
end