classdef tbNewToolbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FilterToolboxesEditField       matlab.ui.control.EditField
        ToolboxesListBox               matlab.ui.control.ListBox
        CreateButton                   matlab.ui.control.Button
        CreatenewtoolboxLabel          matlab.ui.control.Label
        DependenciesLabel              matlab.ui.control.Label
        ToolboxnameEditFieldLabel      matlab.ui.control.Label
        ToolboxnameEditField           matlab.ui.control.EditField
        ShortDecriptionEditFieldLabel  matlab.ui.control.Label
        ShortDecriptionEditField       matlab.ui.control.EditField
        SubfolderEditFieldLabel        matlab.ui.control.Label
        SubfolderEditField             matlab.ui.control.EditField
        cdToFolderEditFieldLabel       matlab.ui.control.Label
        cdToFolderEditField            matlab.ui.control.EditField
        PathplacementDropDownLabel     matlab.ui.control.Label
        PathplacementDropDown          matlab.ui.control.DropDown
        DeployButton                   matlab.ui.control.Button
        CopytoclipboardButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        toolboxNames
    end
    
    methods
        function checkHubInstallation(~)
            cmd = 'hub --version';
            [~, out] = system(cmd);
            assert(contains(out, 'hub version'), 'tbtb:HubNotFound', ...
                'Cannot find hub. Please install from <a href="https://github.com/github/hub#installation">Github</a>.')
        end
        
        function createLocalGitRepo(app)
            cmd = 'git init';
            system(cmd)
        end
        
        function url = createRemoteGitRepo(app)
            app.checkHubInstallation
            cmd = ['hub create -d "' app.ShortDecriptionEditField.Value '"'];
            [tf, out] = system(cmd);
            assert(tf == 0, 'hub create failed')
            lines = split(strip(out));
            url = lines{end};
        end
        
        function toolboxName = getCurrentToolboxName(app, currentRoot)
            toolboxRoot = strrep(tbGetPersistentPrefs().toolboxRoot, '\', '/');
            toolboxName = erase(strrep(currentRoot, '\', '/'), toolboxRoot);
            
            % delete leading slash
            toolboxName = regexprep(toolboxName, '^/', '');
        end
        
        function gitRoot = getGitRoot(app)
            [retVal, out] = system('git rev-parse --show-toplevel');
            if retVal == 0
                gitRoot = strip(out);
            else
                gitRoot = [];
            end
        end
        
        function wellFormedRecord = getMainRecord(app, toolboxName, url)
            record.name = toolboxName;
            record.subfolder = app.SubfolderEditField.Value;
            record.type = 'git';
            record.url = url;
            record.pathPlacement = app.PathplacementDropDown.Value;
            record.cdToFolder = app.cdToFolderEditField.Value;
            wellFormedRecord = tbToolboxRecord(record);
        end
        
        function wellFormedRecords = getDependencyRecords(app)
            deps = app.ToolboxesListBox.Value;
            wellFormedRecords = tbToolboxRecord;
            for k = 1:length(deps)
                record.name = strrep(deps{k}, '\', '/');
                record.type = 'include';
                wellFormedRecords(k) = tbToolboxRecord(record, 'pathPlacement', '');
            end
        end
        
        function records = getRecords(app, toolboxName, url)
            mainRecord = app.getMainRecord(toolboxName, url);
            dependencyRecords = app.getDependencyRecords;
            records = [mainRecord, dependencyRecords];
        end
        
        function filePath = getConfigFilePath(app, toolboxName)
            prefs = tbParsePrefs(tbGetPersistentPrefs);
            registryRoot = tbLocateToolbox(prefs.registry);
            configRoot = fullfile(registryRoot, prefs.registry.subfolder);
            [~, configDirs] = fileparts(toolboxName);
            folder = fullfile(configRoot, configDirs);
            if ~isfolder(folder)
                mkdir(folder)
            end
            filePath = fullfile(configRoot, [toolboxName '.json']);
        end
        
        function writeRecords(app, records, filePath)
            savejson('', records, filePath)
        end
        
        function createToolboxx(app)
            gitRoot = app.getGitRoot;
            if isempty(gitRoot)
                app.createLocalGitRepo
                gitRoot = pwd;
            end
            
            url = app.createRemoteGitRepo;
            toolboxName = app.getCurrentToolboxName(gitRoot);
            records = app.getRecords(toolboxName, url);
            filePath = app.getConfigFilePath(toolboxName);
            app.writeRecords(records, filePath)
            
        end
        
        function tbUseAndClose(app)
            selectedToolbox = app.ToolboxesListBox.Value;
            disp(['deploy toolbox ' selectedToolbox])
            tbUse(selectedToolbox);
            app.delete;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app)
            % Dependency list
            [~, app.toolboxNames] = tbGetToolboxNames;
            app.ToolboxesListBox.Items = app.toolboxNames;
                     
            % Toolbox name
            gitRoot = app.getGitRoot;
            if isempty(gitRoot)
                % no git exist yet, assume this is the root dir for a
                % future git repo
                gitRoot = pwd;
            end
            app.ToolboxnameEditField.Value = app.getCurrentToolboxName(gitRoot);
        end

        % Value changing function: FilterToolboxesEditField
        function updateFilter(app, event)
            filterStr = event.Value;
            idx = ~cellfun(@isempty, regexpi(app.toolboxNames, filterStr));
            filteredToolboxNames = app.toolboxNames(idx);
            app.ToolboxesListBox.Items = filteredToolboxNames;
        end

        % Button pushed function: CreateButton
        function createToolbox(app, event)
            gitRoot = app.getGitRoot;
            if isempty(gitRoot)
                app.createLocalGitRepo
                gitRoot = pwd;
            end
            
            url = app.createRemoteGitRepo;
            toolboxName = app.getCurrentToolboxName(gitRoot);
            records = app.getRecords(toolboxName, url);
            filePath = app.getConfigFilePath(toolboxName);
            app.writeRecords(records, filePath)            
        end

        % Callback function
        function copyToClipboardAndClose(app, event)
            
        end

        % Button pushed function: DeployButton
        function deployNewRepo(app, event)
            tbUse(app.ToolboxnameEditField.Value);
        end

        % Button pushed function: CopytoclipboardButton
        function copyToClipboard(app, event)
            newToolbox = app.ToolboxnameEditField.Value;
            str = ['tbUse(''' newToolbox ''');'];
            disp(['copy "' str '" to clipboard']);
            clipboard('copy', str);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FilterToolboxesEditField = uieditfield(app.UIFigure, 'text');
            app.FilterToolboxesEditField.ValueChangingFcn = createCallbackFcn(app, @updateFilter, true);
            app.ToolboxesListBox = uilistbox(app.UIFigure);
            app.ToolboxesListBox.Multiselect = 'on';
            app.ToolboxesListBox.Value = {};
            app.CreateButton = uibutton(app.UIFigure, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @createToolbox, true);
            app.CreateButton.Text = 'Create';
            app.CreatenewtoolboxLabel = uilabel(app.UIFigure);
            app.CreatenewtoolboxLabel.Text = 'Create new toolbox';
            app.DependenciesLabel = uilabel(app.UIFigure);
            app.DependenciesLabel.Text = 'Dependencies';
            app.ToolboxnameEditFieldLabel = uilabel(app.UIFigure);
            app.ToolboxnameEditFieldLabel.Text = 'Toolbox name';
            app.ToolboxnameEditField = uieditfield(app.UIFigure, 'text');
            app.ToolboxnameEditField.Editable = 'off';
            app.ToolboxnameEditField.Enable = 'off';
            app.ShortDecriptionEditFieldLabel = uilabel(app.UIFigure);
            app.ShortDecriptionEditFieldLabel.Text = 'Short Decription';
            app.ShortDecriptionEditField = uieditfield(app.UIFigure, 'text');
            app.SubfolderEditFieldLabel = uilabel(app.UIFigure);
            app.SubfolderEditFieldLabel.Text = 'Subfolder';
            app.SubfolderEditField = uieditfield(app.UIFigure, 'text');
            app.cdToFolderEditFieldLabel = uilabel(app.UIFigure);
            app.cdToFolderEditFieldLabel.Text = 'cdToFolder';
            app.cdToFolderEditField = uieditfield(app.UIFigure, 'text');
            app.PathplacementDropDownLabel = uilabel(app.UIFigure);
            app.PathplacementDropDownLabel.Text = 'Path placement';
            app.PathplacementDropDown = uidropdown(app.UIFigure);
            app.PathplacementDropDown.Items = {'none', 'prependrootonly', 'prepend', 'appendrootonly', 'append', 'mltbx'};
            app.PathplacementDropDown.Value = 'append';
            app.DeployButton = uibutton(app.UIFigure, 'push');
            app.DeployButton.ButtonPushedFcn = createCallbackFcn(app, @deployNewRepo, true);
            app.DeployButton.Text = 'Deploy';
            app.CopytoclipboardButton = uibutton(app.UIFigure, 'push');
            app.CopytoclipboardButton.ButtonPushedFcn = createCallbackFcn(app, @copyToClipboard, true);
            app.CopytoclipboardButton.Text = 'Copy to clipboard';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tbNewToolbox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @init)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end